classdef FilamentWinding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DropDown                        matlab.ui.control.DropDown
        CalculateButton                 matlab.ui.control.Button
        XZPlaneButton                   matlab.ui.control.Button
        YZPlaneButton                   matlab.ui.control.Button
        XYPlaneButton                   matlab.ui.control.Button
        IsometricButton                 matlab.ui.control.Button
        ProgressSlider                  matlab.ui.control.Slider
        LayersPanel                     matlab.ui.container.Panel
        MoveDownButton                  matlab.ui.control.Button
        MoveUpButton                    matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ExportButton                    matlab.ui.control.Button
        ClearLayerButton                matlab.ui.control.Button
        AddLayerButton                  matlab.ui.control.Button
        FilamentPropertiesPanel         matlab.ui.container.Panel
        FilamentWidthEditField          matlab.ui.control.NumericEditField
        FilamentWidthEditFieldLabel     matlab.ui.control.Label
        FilamentStrengthEditField       matlab.ui.control.NumericEditField
        FilamentStrengthEditFieldLabel  matlab.ui.control.Label
        FilamentThicknessEditField      matlab.ui.control.NumericEditField
        FilamentThicknessEditFieldLabel  matlab.ui.control.Label
        DesignParametersPanel           matlab.ui.container.Panel
        FrictionFactorEditField         matlab.ui.control.NumericEditField
        FrictionFactorEditFieldLabel    matlab.ui.control.Label
        PressureEditField               matlab.ui.control.NumericEditField
        PressureEditFieldLabel          matlab.ui.control.Label
        SafetyFactorEditField           matlab.ui.control.NumericEditField
        SafetyFactorEditFieldLabel      matlab.ui.control.Label
        VesselGeomteryPanel             matlab.ui.container.Panel
        CylinderLengthEditField         matlab.ui.control.NumericEditField
        CylinderLengthEditFieldLabel    matlab.ui.control.Label
        InletDiameterEditField          matlab.ui.control.NumericEditField
        InletDiameterEditFieldLabel     matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        WindAngleSliderLabel            matlab.ui.control.Label
        WindAngleSlider                 matlab.ui.control.Slider
        MoldShapeButtonGroup            matlab.ui.container.ButtonGroup
        CylinderwithCapsButton          matlab.ui.control.RadioButton
        CylinderButton                  matlab.ui.control.RadioButton
        SphereButton                    matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        alpha %wind angle (radians)
        S_F %fiber strength
        P %pressure
        N %safety factor
        D % Diameter
        R %Radius
        w %filament width
        t %filament thickness
        r_0 %starting radius
        d_0 %starting diameter
        n_pts %number of points
        ff %friction factor
        g % g-code
        G % surface mold array
        alpha_helical % helical wind angle
        cyl_Len %cylinder Length
        i %index

        alpha_list %array used in netting analysis
        t_helical %thikness array for helical layers
        t_hoop %thickness array for hoop layers
        n_helical %number array for helical layers
        n_hoop %number array for hoop layers
        selected_layer %layer selected
    end
    
    methods (Access = private)
        
        function Netting_Analysis(app)
            max_alpha = 54;
            app.alpha_list = linspace(1,max_alpha, 54); %local alpha variable
            len = 54;
            toRad = pi / 180;
           
            S_D = app.S_F / app.N; %design strength
            N_hoop = app.P * app.R; %hoop force
            N_axial = N_hoop / 2; %axial force

            app.t_helical = zeros(1,len); %hoop thickness
            app.t_hoop = zeros(1,len); %helical thickness

            for k = 1:len
                app.t_helical(k) = N_axial / (S_D * cos(app.alpha_list(k)*toRad)^2); %hoop thickness
                app.t_hoop(k) = (N_hoop - N_axial * tan(app.alpha_list(k)*toRad)^2) / S_D; %helical thickness
            end

            app.n_hoop = ceil(app.t_hoop / app.t); %returns number of hoop wraps
            app.n_helical = ceil(app.t_helical / app.t); %returns number of helix wraps

        end
        
        function plot3D(app)
            %cla(app.UIAxes)
            %plot3d(app.UIAxes, app.g(1,:), app.g(2,:), app.g(3,:)) %plots x,y,z filament 
            
        end
        
        function plot_Net_Analysis(app)
            cla(app.UIAxes) %clears plot
            hold(app.UIAxes, "all")
            yyaxis(app.UIAxes, "left")
            plot(app.UIAxes, app.alpha_list, app.n_helical);
            plot(app.UIAxes, app.alpha_list, app.n_hoop)
            ylabel(app.UIAxes,"Number of Layers")
            ylim(app.UIAxes, [0, app.n_helical(end) + 1])

            yyaxis(app.UIAxes, "right")
            plot(app.UIAxes, app.alpha_list, app.t_helical);
            plot(app.UIAxes, app.alpha_list, app.t_hoop);
            ylabel(app.UIAxes, "Thickness (in)")

            hold(app.UIAxes, "off")
            xlabel(app.UIAxes, "Wind Angle (degrees)")
            xlim(app.UIAxes, [0, app.alpha_list(end)])
            
            title(app.UIAxes, "Helical and Hoop Layers v.s Wind Angles")
            legend(app.UIAxes, 'Helical layers', 'Hoop layers', 'Helical thickness', 'Hoop thickness', 'Location', 'northwest')
        end
        
        function pop_tab(app)
            
            hoop = app.n_hoop(app.selected_layer);
            helical = app.n_helical(app.selected_layer);
            
            wind_angle = app.alpha_list(app.selected_layer);
            
            app.UITable.Data = {};
            
            for k = 1:hoop %add hoop winds to table
                new_data = {90};
                app.UITable.Data = [app.UITable.Data; new_data];
            end

            for k = 1: helical %add helical winds to table
                new_data = {wind_angle};
                app.UITable.Data = [app.UITable.Data; new_data];
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.D = app.DiameterEditField.Value;
            app.R = app.DiameterEditField.Value / 2;
            app.d_0 = app.InletDiameterEditField.Value;
            app.r_0 = app.InletDiameterEditField.Value /2;
            app.t = app.FilamentThicknessEditField.Value;
            app.w = app.FilamentWidthEditField.Value;
            app.S_F = app.FilamentStrengthEditField.Value;
            app.P = app.PressureEditField.Value;
            app.N = app.SafetyFactorEditField.Value;
            app.ff = app.FrictionFactorEditField.Value;
            app.cyl_Len = app.CylinderLengthEditField.Value;
            app.n_pts = 60;
        end

        % Selection changed function: MoldShapeButtonGroup
        function MoldShapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.MoldShapeButtonGroup.SelectedObject;
            
        end

        % Callback function
        function UITable2CellSelection(app, event)
            app.i = event.Indices; 
     
        end

        % Callback function: CalculateButton, UITable
        function CalculateButtonPushed(app, event)
            Netting_Analysis(app);
            len = length(app.n_hoop);
          
            for k = 1: len
                app.DropDown.Items{1,k} = [num2str(app.alpha_list(k)) ', ' num2str(app.n_hoop(k))  ', '  num2str(app.n_helical(k))]; 
                app.DropDown.ItemsData{1,k} = [app.alpha_list(k) app.n_hoop(k) app.n_helical(k)];
            end
            plot_Net_Analysis(app);

        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            app.D = app.DiameterEditField.Value;
            app.R = app.D / 2;
        end

        % Value changed function: InletDiameterEditField
        function InletDiameterEditFieldValueChanged(app, event)
            app.d_0 = app.InletDiameterEditField.Value;
            app.r_0 = app.d_0 / 2;
        end

        % Value changed function: CylinderLengthEditField
        function CylinderLengthEditFieldValueChanged(app, event)
            app.cyl_Len = app.CylinderLengthEditField.Value;
            
        end

        % Value changed function: PressureEditField
        function PressureEditFieldValueChanged(app, event)
            app.P = app.PressureEditField.Value;
            
        end

        % Value changed function: SafetyFactorEditField
        function SafetyFactorEditFieldValueChanged(app, event)
            app.N = app.SafetyFactorEditField.Value;
            
        end

        % Value changed function: FrictionFactorEditField
        function FrictionFactorEditFieldValueChanged(app, event)
            app.ff = app.FrictionFactorEditField.Value;
            
        end

        % Value changed function: FilamentStrengthEditField
        function FilamentStrengthEditFieldValueChanged(app, event)
            app.S_F = app.FilamentStrengthEditField.Value;
            
        end

        % Value changed function: FilamentThicknessEditField
        function FilamentThicknessEditFieldValueChanged(app, event)
            app.t = app.FilamentThicknessEditField.Value;
            
        end

        % Value changed function: FilamentWidthEditField
        function FilamentWidthEditFieldValueChanged(app, event)
            app.w = app.FilamentWidthEditField.Value;
            
        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            %disp(class(item)) item is a double

            app.selected_layer = item;
            pop_tab(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            %pop_tab(app)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.selected_layer = indices(1,1);
            plot3D(app);
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            if app.selected_layer < 2
                %already at first element cannot move up anymore
            else
                below_selected = app.UITable.Data(app.selected_layer+1 : end);
                above = app.UITable.Data(app.selected_layer-1);
                selected = app.UITable.Data(app.selected_layer);

                app.UITable.Data(app.selected_layer-1:end) = [selected; above; below_selected];
                app.selected_layer = app.selected_layer - 1;
            end

        end

        % Button down function: MoldShapeButtonGroup
        function MoldShapeButtonGroupButtonDown(app, event)
            
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            if app.selected_layer == length(app.UITable.Data)
                %already at bottom element cannot move down anymore
            else
                above_selected = app.UITable.Data(1 : app.selected_layer - 1);
                below = app.UITable.Data(app.selected_layer + 1);
                selected = app.UITable.Data(app.selected_layer);
                app.UITable.Data(1:app.selected_layer + 1) = [above_selected; below; selected];
                app.selected_layer = app.selected_layer + 1;
            end
        end

        % Clicked callback: UITable
        function UITableClicked(app, event)
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;
            app.selected_layer = displayRow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Selected Layer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MoldShapeButtonGroup = uibuttongroup(app.UIFigure);
            app.MoldShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MoldShapeButtonGroupSelectionChanged, true);
            app.MoldShapeButtonGroup.Title = 'Mold Shape';
            app.MoldShapeButtonGroup.ButtonDownFcn = createCallbackFcn(app, @MoldShapeButtonGroupButtonDown, true);
            app.SphereButton = uiradiobutton(app.MoldShapeButtonGroup);
            app.SphereButton.Text = 'Sphere';
            app.SphereButton.Value = true;
            app.CylinderButton = uiradiobutton(app.MoldShapeButtonGroup);
            app.CylinderButton.Text = 'Cylinder';
            app.CylinderwithCapsButton = uiradiobutton(app.MoldShapeButtonGroup);
            app.CylinderwithCapsButton.Text = 'Cylinder with Caps';
            app.WindAngleSlider = uislider(app.UIFigure);
            app.WindAngleSlider.Limits = [0 90];
            app.WindAngleSliderLabel = uilabel(app.UIFigure);
            app.WindAngleSliderLabel.Text = 'Wind Angle';
            app.VesselGeomteryPanel = uipanel(app.UIFigure);
            app.VesselGeomteryPanel.Title = 'Vessel Geomtery';
            app.DiameterEditFieldLabel = uilabel(app.VesselGeomteryPanel);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.VesselGeomteryPanel, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.DiameterEditField.Value = 8;
            app.InletDiameterEditFieldLabel = uilabel(app.VesselGeomteryPanel);
            app.InletDiameterEditFieldLabel.Text = 'Inlet Diameter';
            app.InletDiameterEditField = uieditfield(app.VesselGeomteryPanel, 'numeric');
            app.InletDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @InletDiameterEditFieldValueChanged, true);
            app.InletDiameterEditField.Value = 1;
            app.CylinderLengthEditFieldLabel = uilabel(app.VesselGeomteryPanel);
            app.CylinderLengthEditFieldLabel.Text = 'Cylinder Length';
            app.CylinderLengthEditField = uieditfield(app.VesselGeomteryPanel, 'numeric');
            app.CylinderLengthEditField.ValueChangedFcn = createCallbackFcn(app, @CylinderLengthEditFieldValueChanged, true);
            app.CylinderLengthEditField.Value = 16;
            app.DesignParametersPanel = uipanel(app.UIFigure);
            app.DesignParametersPanel.Title = 'Design Parameters';
            app.SafetyFactorEditFieldLabel = uilabel(app.DesignParametersPanel);
            app.SafetyFactorEditFieldLabel.Text = 'Safety Factor';
            app.SafetyFactorEditField = uieditfield(app.DesignParametersPanel, 'numeric');
            app.SafetyFactorEditField.ValueChangedFcn = createCallbackFcn(app, @SafetyFactorEditFieldValueChanged, true);
            app.SafetyFactorEditField.Value = 2;
            app.PressureEditFieldLabel = uilabel(app.DesignParametersPanel);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.DesignParametersPanel, 'numeric');
            app.PressureEditField.ValueChangedFcn = createCallbackFcn(app, @PressureEditFieldValueChanged, true);
            app.PressureEditField.Value = 2000;
            app.FrictionFactorEditFieldLabel = uilabel(app.DesignParametersPanel);
            app.FrictionFactorEditFieldLabel.Text = 'Friction Factor';
            app.FrictionFactorEditField = uieditfield(app.DesignParametersPanel, 'numeric');
            app.FrictionFactorEditField.ValueChangedFcn = createCallbackFcn(app, @FrictionFactorEditFieldValueChanged, true);
            app.FilamentPropertiesPanel = uipanel(app.UIFigure);
            app.FilamentPropertiesPanel.Title = 'Filament Properties';
            app.FilamentThicknessEditFieldLabel = uilabel(app.FilamentPropertiesPanel);
            app.FilamentThicknessEditFieldLabel.Text = 'Filament Thickness';
            app.FilamentThicknessEditField = uieditfield(app.FilamentPropertiesPanel, 'numeric');
            app.FilamentThicknessEditField.ValueChangedFcn = createCallbackFcn(app, @FilamentThicknessEditFieldValueChanged, true);
            app.FilamentThicknessEditField.Value = 0.004;
            app.FilamentStrengthEditFieldLabel = uilabel(app.FilamentPropertiesPanel);
            app.FilamentStrengthEditFieldLabel.Text = 'Filament Strength';
            app.FilamentStrengthEditField = uieditfield(app.FilamentPropertiesPanel, 'numeric');
            app.FilamentStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @FilamentStrengthEditFieldValueChanged, true);
            app.FilamentStrengthEditField.Value = 710000;
            app.FilamentWidthEditFieldLabel = uilabel(app.FilamentPropertiesPanel);
            app.FilamentWidthEditFieldLabel.Text = 'Filament Width';
            app.FilamentWidthEditField = uieditfield(app.FilamentPropertiesPanel, 'numeric');
            app.FilamentWidthEditField.ValueChangedFcn = createCallbackFcn(app, @FilamentWidthEditFieldValueChanged, true);
            app.FilamentWidthEditField.Value = 0.25;
            app.LayersPanel = uipanel(app.UIFigure);
            app.LayersPanel.Title = 'Layers';
            app.AddLayerButton = uibutton(app.LayersPanel, 'push');
            app.AddLayerButton.Text = 'Add Layer';
            app.ClearLayerButton = uibutton(app.LayersPanel, 'push');
            app.ClearLayerButton.Text = 'Clear Layer';
            app.ExportButton = uibutton(app.LayersPanel, 'push');
            app.ExportButton.Text = 'Export';
            app.UITable = uitable(app.LayersPanel);
            app.UITable.ColumnName = {'Wind Angle'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.ClickedFcn = createCallbackFcn(app, @UITableClicked, true);
            app.MoveUpButton = uibutton(app.LayersPanel, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.LayersPanel, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.ProgressSlider = uislider(app.UIFigure);
            app.IsometricButton = uibutton(app.UIFigure, 'push');
            app.IsometricButton.Text = 'Isometric';
            app.XYPlaneButton = uibutton(app.UIFigure, 'push');
            app.XYPlaneButton.Text = 'XY Plane';
            app.YZPlaneButton = uibutton(app.UIFigure, 'push');
            app.YZPlaneButton.Text = 'YZ Plane';
            app.XZPlaneButton = uibutton(app.UIFigure, 'push');
            app.XZPlaneButton.Text = 'XZ Plane';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FilamentWinding

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end